classdef ProbWeightingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Step3Panel         matlab.ui.container.Panel
        YprimeInputField   matlab.ui.control.NumericEditField
        Label_5            matlab.ui.control.Label
        Step3NextButton    matlab.ui.control.Button
        Step3Label         matlab.ui.control.Label
        Step2Panel         matlab.ui.container.Panel
        Step2NextButton    matlab.ui.control.Button
        SInputField        matlab.ui.control.NumericEditField
        Label_4            matlab.ui.control.Label
        Step2Label         matlab.ui.control.Label
        Step1Panel         matlab.ui.container.Panel
        Step1NextButton    matlab.ui.control.Button
        YInputField        matlab.ui.control.NumericEditField
        Label_3            matlab.ui.control.Label
        Step1Label         matlab.ui.control.Label
        Step4Panel         matlab.ui.container.Panel
        PreferYButton      matlab.ui.control.Button
        IndifferentButton  matlab.ui.control.Button
        PreferXButton      matlab.ui.control.Button
        Step4Label         matlab.ui.control.Label
        IdNamePanel        matlab.ui.container.Panel
        StartButton        matlab.ui.control.Button
        NameField          matlab.ui.control.EditField
        Label_2            matlab.ui.control.Label
        IDField            matlab.ui.control.EditField
        Label              matlab.ui.control.Label
    end

    
    properties (Access = private)
        TrialIndex = 1
        TotalTrials = 10
        DataLog = struct

        % 変数格納
        p, q, r
        x, xprime
        y, s, yprime
        N  % N乗
    end
    
    methods (Access = private)
        
        function nextTrial(app)
            if app.TrialIndex > app.TotalTrials
                app.finishExperiment();
                return;
            end

            % 確率と報酬のランダム生成
            gridProb = 0.1:0.1:0.9;
            gridX = 10:10:100;
            % p > q になるまで再抽選
            while true
                app.p = randsample(gridProb, 1);
                app.q = randsample(gridProb, 1);
                if app.p > app.q
                    break;
                end
            end
            app.r = randsample(gridProb, 1);
            app.x = randsample(gridX, 1);
            app.xprime = randsample(gridX, 1);

            % Nを2または3からランダムに選ぶ
            app.N = randsample([2, 3], 1);
            %app.N = 2;

            % Step1開始
            app.Step1Label.Text = sprintf('(%d/%d)  (p=%.4f, x=%.0f) ～ (p=%.4f, x=?)', ...
                app.TrialIndex, app.TotalTrials, app.p, app.x, app.q);
            app.YInputField.Value = [];
            app.Step1Panel.Visible = 'on';
        end
        
        
        function drecordFinalChoice(app, choice)
            app.Step4Panel.Visible = 'off';
            i = app.TrialIndex;
            app.DataLog(i).p = app.p;
            app.DataLog(i).q = app.q;
            app.DataLog(i).r = app.r;
            app.DataLog(i).x = app.x;
            app.DataLog(i).xprime = app.xprime;
            app.DataLog(i).y = app.y;
            app.DataLog(i).s = app.s;
            app.DataLog(i).yprime = app.yprime;
            app.DataLog(i).N = app.N;
            app.DataLog(i).choice = choice;
            app.DataLog(i).trial = i;
            app.TrialIndex = app.TrialIndex + 1;
            app.nextTrial();
        end
        
        function finishExperiment(app)
            id = app.IDField.Value;
            filename = sprintf('ProbTask_%s_%s', id, datestr(now,'yyyymmdd_HHMMSS'));
            save([filename '.mat'], 'app');

            % Excel変換
            T = struct2table(app.DataLog);
            writetable(T, [filename '.xlsx']);
            uialert(app.UIFigure, 'データを保存しました。アプリを終了します。', '完了');
            pause(3);
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Step1Panel.Visible = 'off';
            app.Step2Panel.Visible = 'off';
            app.Step3Panel.Visible = 'off';
            app.Step4Panel.Visible = 'off';
            app.IdNamePanel.Visible = 'on';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.IdNamePanel.Visible = 'off';
            app.nextTrial(); 
        end

        % Button pushed function: Step1NextButton
        function Step1Next(app, event)
            y = app.YInputField.Value;
            if isnan(y) || y < 1 || y > 100000000
                uialert(app.UIFigure, 'yは1～100000000の範囲で入力してください', '入力エラー');
                return;
            end
            app.y = y;
            app.Step1Panel.Visible = 'off';

            app.Step2Label.Text = sprintf('(%d/%d)  (p=%.4f, x=%.0f) ～ (p=?, x=%.0f)', ...
                app.TrialIndex, app.TotalTrials, app.r, app.x, app.y);
            app.SInputField.Value = [];
            app.Step2Panel.Visible = 'on';
        end

        % Button pushed function: Step2NextButton
        function Step2Next(app, event)
            s = app.SInputField.Value;
            if isnan(s) || s < 0 || s > 1
                uialert(app.UIFigure, 'sは0～1の範囲で入力してください', '入力エラー');
                return;
            end
            app.s = s;
            app.Step2Panel.Visible = 'off';

            %pN = app.p^app.N * 100;
            %qN = app.q^app.N * 100;
            pN = app.p^app.N;
            qN = app.q^app.N;
            app.Step3Label.Text = sprintf('(%d/%d)  (p=%.4f, x=%.0f) ～ (p=%.4f, x=?)', ...
                app.TrialIndex, app.TotalTrials, pN, app.xprime, qN);
            app.YprimeInputField.Value = [];
            app.Step3Panel.Visible = 'on';
        end

        % Button pushed function: Step3NextButton
        function Step3Next(app, event)
            yprime = app.YprimeInputField.Value;
            if isnan(yprime) || yprime < 1 || yprime > 100000000
                uialert(app.UIFigure, 'y''は1～100000000の範囲で入力してください', '入力エラー');
                return;
            end
            app.yprime = yprime;
            app.Step3Panel.Visible = 'off';

            %rN = app.r^app.N * 100;
            %sN = app.s^app.N * 100;
            rN = app.r^app.N;
            sN = app.s^app.N;
            app.Step4Label.Text = sprintf('(%d/%d)  X: (p=%.4f, x=%.0f)  vs  Y: (p=%.4f, x=%.0f)', ...
            app.TrialIndex, app.TotalTrials, rN, app.xprime, sN, app.yprime);
            app.Step4Panel.Visible = 'on';
        end

        % Callback function
        function recordFinalChoice(app, event)
            
        end

        % Button pushed function: PreferXButton
        function PreferXButtonPushed(app, event)
            drecordFinalChoice(app, 'X');
        end

        % Button pushed function: IndifferentButton
        function IndifferentButtonPushed(app, event)
            drecordFinalChoice(app, 'Indifferent');
        end

        % Button pushed function: PreferYButton
        function PreferYButtonPushed(app, event)
            drecordFinalChoice(app, 'Y');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IdNamePanel = uipanel(app.UIFigure);
            app.Label = uilabel(app.IdNamePanel);
            app.Label.Text = '学籍番号';
            app.IDField = uieditfield(app.IdNamePanel, 'text');
            app.Label_2 = uilabel(app.IdNamePanel);
            app.Label_2.Text = '氏　名　';
            app.NameField = uieditfield(app.IdNamePanel, 'text');
            app.StartButton = uibutton(app.IdNamePanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '実験を始める';
            app.Step4Panel = uipanel(app.UIFigure);
            app.Step4Label = uilabel(app.Step4Panel);
            app.Step4Label.Text = '';
            app.PreferXButton = uibutton(app.Step4Panel, 'push');
            app.PreferXButton.ButtonPushedFcn = createCallbackFcn(app, @PreferXButtonPushed, true);
            app.PreferXButton.Text = 'Xを好む';
            app.IndifferentButton = uibutton(app.Step4Panel, 'push');
            app.IndifferentButton.ButtonPushedFcn = createCallbackFcn(app, @IndifferentButtonPushed, true);
            app.IndifferentButton.Text = 'ほとんど無差別';
            app.PreferYButton = uibutton(app.Step4Panel, 'push');
            app.PreferYButton.ButtonPushedFcn = createCallbackFcn(app, @PreferYButtonPushed, true);
            app.PreferYButton.Text = 'Yを好む';
            app.Step1Panel = uipanel(app.UIFigure);
            app.Step1Label = uilabel(app.Step1Panel);
            app.Step1Label.Text = '';
            app.Label_3 = uilabel(app.Step1Panel);
            app.Label_3.Text = 'ｘの金額';
            app.YInputField = uieditfield(app.Step1Panel, 'numeric');
            app.YInputField.AllowEmpty = 'on';
            app.Step1NextButton = uibutton(app.Step1Panel, 'push');
            app.Step1NextButton.ButtonPushedFcn = createCallbackFcn(app, @Step1Next, true);
            app.Step1NextButton.Text = '次へ';
            app.Step2Panel = uipanel(app.UIFigure);
            app.Step2Label = uilabel(app.Step2Panel);
            app.Step2Label.Text = '';
            app.Label_4 = uilabel(app.Step2Panel);
            app.Label_4.Text = 'ｐの確率：';
            app.SInputField = uieditfield(app.Step2Panel, 'numeric');
            app.SInputField.AllowEmpty = 'on';
            app.Step2NextButton = uibutton(app.Step2Panel, 'push');
            app.Step2NextButton.ButtonPushedFcn = createCallbackFcn(app, @Step2Next, true);
            app.Step2NextButton.Text = '次へ';
            app.Step3Panel = uipanel(app.UIFigure);
            app.Step3Label = uilabel(app.Step3Panel);
            app.Step3Label.Text = '';
            app.Step3NextButton = uibutton(app.Step3Panel, 'push');
            app.Step3NextButton.ButtonPushedFcn = createCallbackFcn(app, @Step3Next, true);
            app.Step3NextButton.Text = '次へ';
            app.Label_5 = uilabel(app.Step3Panel);
            app.Label_5.Text = 'ｘの金額';
            app.YprimeInputField = uieditfield(app.Step3Panel, 'numeric');
            app.YprimeInputField.AllowEmpty = 'on';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProbWeightingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end